classdef controls_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ClosegripperButton         matlab.ui.control.Button
        OpengripperButton          matlab.ui.control.Button
        BaseSlider                 matlab.ui.control.Slider
        BaseSliderLabel            matlab.ui.control.Label
        WristRotateSlider          matlab.ui.control.Slider
        ShoulderSlider_2Label_2    matlab.ui.control.Label
        ShoulderSlider             matlab.ui.control.Slider
        ShoulderSlider_2Label      matlab.ui.control.Label
        ElbowSlider_2              matlab.ui.control.Slider
        ElbowSlider_2Label         matlab.ui.control.Label
        WristPitchSlider           matlab.ui.control.Slider
        WristPitchSliderLabel      matlab.ui.control.Label
        ConnectionstatusLamp       matlab.ui.control.Lamp
        ConnectionstatusLampLabel  matlab.ui.control.Label
        BrakeButton                matlab.ui.control.Button
        EmergencyStopButton        matlab.ui.control.Button
        RightButton                matlab.ui.control.Button
        LeftButton                 matlab.ui.control.Button
        BackwardButton             matlab.ui.control.Button
        ForwardButton              matlab.ui.control.Button
        VelocitySlider             matlab.ui.control.Slider
        VelocitySliderLabel        matlab.ui.control.Label
        Button                     matlab.ui.control.Button
        forwardVisionAxes          matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = public)
        sim            % remoteApi object
        clientID       % Connection ID
        jointHandles   % Struct() storing the joint handles
        visionSensorHandles % Struct() storing the vision sensor handles
        movementSpeed  double = 0 % This will store the slider value
    end

    properties (Access = private)
        VisionTimer  % Timer object for updating the vision sensor
    end
    
    methods (Access = private)
        function moveBotForwardBackward(app, velocity)
            % Set the velocity for the right rear rolling joint
            app.sim.simxSetJointTargetVelocity(app.clientID, ...
            app.jointHandles.rollingJoint_rr, ...
            velocity, ...
            app.sim.simx_opmode_streaming);
        
            % Set the velocity for the left rear rolling joint
            app.sim.simxSetJointTargetVelocity(app.clientID, ...
            app.jointHandles.rollingJoint_rl, ...
            velocity, ...
            app.sim.simx_opmode_streaming);
        
            % Set the velocity for the right front rolling joint
            app.sim.simxSetJointTargetVelocity(app.clientID, ...
            app.jointHandles.rollingJoint_fr, ...
            velocity, ...
            app.sim.simx_opmode_streaming);
        
            % Set the velocity for the left front rolling joint
            app.sim.simxSetJointTargetVelocity(app.clientID, ...
            app.jointHandles.rollingJoint_fl, ...
            velocity, ...
            app.sim.simx_opmode_streaming);
    
            fprintf('Robot moving at speed %d\n', velocity);
        end

        function moveSideways(app, velocity)
            % Set the velocity for the front rear rolling joint
            app.sim.simxSetJointTargetVelocity(app.clientID, ...
                app.jointHandles.rollingJoint_fr, ...
                -velocity, ...
                app.sim.simx_opmode_streaming);

            app.sim.simxSetJointTargetVelocity(app.clientID, ...
                app.jointHandles.rollingJoint_fl, ...
                velocity, ...
                app.sim.simx_opmode_streaming);

            app.sim.simxSetJointTargetVelocity(app.clientID, ...
                app.jointHandles.rollingJoint_rr, ...
                velocity, ...
                app.sim.simx_opmode_streaming);

            app.sim.simxSetJointTargetVelocity(app.clientID, ...
                app.jointHandles.rollingJoint_rl, ...
                -velocity, ...
                app.sim.simx_opmode_streaming);

    
            fprintf('Robot moving sideways at speed %d\n', velocity);
        end

        function handleArmJoint(app, jointHandle, angle)
            app.sim.simxSetJointTargetPosition(app.clientID, ...
                jointHandle, ...
                angle, ...
                app.sim.simx_opmode_streaming);
            
            fprintf('Joint moved to %.2f radians\n', angle);
        end

        function smoothSetJointTargetPosition(app, jointHandle, targetPos)
            % Get the current position (blocking call)
            [~, currentPos] = app.sim.simxGetJointPosition(app.clientID, jointHandle, app.sim.simx_opmode_blocking);
            
            % Define smoothing parameters:
            numSteps = 50;         % Number of incremental steps
            dt = 0.02;             % Pause time between steps (seconds)
            stepSize = (targetPos - currentPos) / numSteps;
            
            for i = 1:numSteps
                newPos = currentPos + i * stepSize;
                app.sim.simxSetJointTargetPosition(app.clientID, jointHandle, newPos, app.sim.simx_opmode_oneshot);
                pause(dt);
            end
        end

        function updateVision(app)
            persistent errorLogged;
            if isempty(errorLogged)
                errorLogged = false;
            end
        
            % Ensure client connection and valid sensor handle
            if app.clientID < 0 || isempty(app.visionSensorHandles.forwardVisionSensor)
                if ~errorLogged
                    disp('Vision sensor handle is invalid or not initialized.');
                    errorLogged = true;
                end
                return;
            end
        
            % Attempt to get the vision sensor image
            [res, resolution, imageData] = app.sim.simxGetVisionSensorImage(app.clientID, ...
                app.visionSensorHandles.forwardVisionSensor, 0, ...
                app.sim.simx_opmode_buffer);
        
            if res ~= 0
                if ~errorLogged
                    disp(['Vision sensor retrieval failed. Error code: ', num2str(res)]);
                    errorLogged = true;
                end
                return;
            end
        
            % Check if imageData is valid
            if isempty(imageData)
                if ~errorLogged
                    disp('No image data retrieved.');
                    errorLogged = true;
                end
                return;
            end
        
            % Convert and reshape image
            imageMatrix = reshape(imageData, [resolution(1), resolution(2), 3]);
            imageMatrix = permute(imageMatrix, [2,1,3]); % Adjust orientation
            imageMatrix = flipud(imageMatrix); % Flip for correct view
        
            % Display the image
            if isvalid(app.forwardVisionAxes)
                imshow(uint8(imageMatrix), 'Parent', app.forwardVisionAxes);
            else
                if ~errorLogged
                    disp('UIAxes is invalid or deleted.');
                    errorLogged = true;
                end
            end
        
            % Reset error flag if everything works
            errorLogged = false;
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.sim = remApi('remoteApi');
            app.sim.simxFinish(-1); % just to close any existing connections
    
            % Change the port if needed
            app.clientID = app.sim.simxStart('127.0.0.1', 19000, true, true, 5000, 5);
    
            % Initialize a struct or container to store your joints
            app.jointHandles = struct();
            
            app.visionSensorHandles = struct();
    
            if app.clientID > -1
                disp('Connected to CoppeliaSim');
                
                % Joints
                [~, app.jointHandles.rollingJoint_rr] = app.sim.simxGetObjectHandle(app.clientID, 'rollingJoint_rr', app.sim.simx_opmode_oneshot_wait);
                [~, app.jointHandles.rollingJoint_rl] = app.sim.simxGetObjectHandle(app.clientID, 'rollingJoint_rl', app.sim.simx_opmode_oneshot_wait);
                [~, app.jointHandles.rollingJoint_fr] = app.sim.simxGetObjectHandle(app.clientID, 'rollingJoint_fr', app.sim.simx_opmode_oneshot_wait);
                [~, app.jointHandles.rollingJoint_fl] = app.sim.simxGetObjectHandle(app.clientID, 'rollingJoint_fl', app.sim.simx_opmode_oneshot_wait);

                [~, app.jointHandles.slippingJoint_rr] = app.sim.simxGetObjectHandle(app.clientID, 'slippingJoint_rr', app.sim.simx_opmode_oneshot_wait);
                [~, app.jointHandles.slippingJoint_rl] = app.sim.simxGetObjectHandle(app.clientID, 'slippingJoint_rl', app.sim.simx_opmode_oneshot_wait);
                [~, app.jointHandles.joint] = app.sim.simxGetObjectHandle(app.clientID, 'joint', app.sim.simx_opmode_oneshot_wait);
                [~, app.jointHandles.slippingJoint_fl] = app.sim.simxGetObjectHandle(app.clientID, 'slippingJoint_fl', app.sim.simx_opmode_oneshot_wait);
                [~, app.jointHandles.slippingJoint_fr] = app.sim.simxGetObjectHandle(app.clientID, 'slippingJoint_fr', app.sim.simx_opmode_oneshot_wait);
                [~, app.jointHandles.youBotArmJoint0] = app.sim.simxGetObjectHandle(app.clientID, 'youBotArmJoint0', app.sim.simx_opmode_oneshot_wait);
                [~, app.jointHandles.youBotArmJoint1] = app.sim.simxGetObjectHandle(app.clientID, 'youBotArmJoint1', app.sim.simx_opmode_oneshot_wait);
                [~, app.jointHandles.youBotArmJoint2] = app.sim.simxGetObjectHandle(app.clientID, 'youBotArmJoint2', app.sim.simx_opmode_oneshot_wait);
                [~, app.jointHandles.youBotArmJoint3] = app.sim.simxGetObjectHandle(app.clientID, 'youBotArmJoint3', app.sim.simx_opmode_oneshot_wait);
                [~, app.jointHandles.youBotArmJoint4] = app.sim.simxGetObjectHandle(app.clientID, 'youBotArmJoint4', app.sim.simx_opmode_oneshot_wait);
                [~, app.jointHandles.youBotGripperJoint1] = app.sim.simxGetObjectHandle(app.clientID, 'youBotGripperJoint1', app.sim.simx_opmode_oneshot_wait);
                [~, app.jointHandles.youBotGripperJoint2] = app.sim.simxGetObjectHandle(app.clientID, 'youBotGripperJoint2', app.sim.simx_opmode_oneshot_wait);
                
                % Front vision sensor
                [res, app.visionSensorHandles.forwardVisionSensor] = app.sim.simxGetObjectHandle(app.clientID, ...
                    'visionSensor', ...
                    app.sim.simx_opmode_blocking);

                if res == 0
                    disp('Vision sensor handle acquired.');
                else
                    error('Failed to get vision sensor handle.');
                end

                % Start streaming the vision sensor image (initial call)
                [res, ~, ~] = app.sim.simxGetVisionSensorImage(app.clientID, ...
                    app.visionSensorHandles.forwardVisionSensor, ...
                    0, ...
                    app.sim.simx_opmode_streaming);
                
                if res ~= 0
                    warning('Failed to start vision sensor streaming.');
                end

                pause(5);  % allow streaming to start
                
                % Create and start a timer to update the image every 0.1 seconds
                app.VisionTimer = timer('ExecutionMode', ...
                    'fixedSpacing', ...
                    'Period', ...
                    0.5, ...
                    'TimerFcn', ...
                    @(~,~) updateVision(app));
                
                start(app.VisionTimer);

                % Lamp is green if there is a connection
                app.ConnectionstatusLamp.Color = [0 1 0];
            else
                disp('Connection failed');

                % Lamp is red if there is a no connection
                app.ConnectionstatusLamp.Color = [1 0 0];
            end
        end

        % Value changed function: VelocitySlider
        function VelocitySliderValueChanged(app, event)
            app.movementSpeed = app.VelocitySlider.Value;
            disp(['Slider value set to: ', num2str(app.movementSpeed)]);
            
        end

        % Callback function: ForwardButton, UIFigure
        function moveBotForward(app, event)
            app.moveBotForwardBackward(app.movementSpeed);
        end

        % Button pushed function: BackwardButton
        function moveBotBackward(app, event)
            app.moveBotForwardBackward(-app.movementSpeed);
        end

        % Button pushed function: RightButton
        function moveBotRight(app, event)
            app.moveSideways(app.movementSpeed);
        end

        % Button pushed function: LeftButton
        function LeftButtonPushed(app, event)
            app.moveSideways(-app.movementSpeed);
        end

        % Button pushed function: EmergencyStopButton
        function EmergencyStop(app, event)
            app.moveBotForwardBackward(0);
        end

        % Button pushed function: BrakeButton
        function Brake(app, event)
            desiredVelocity = 0; % Target velocity (fully stopped)
            currentVelocity = app.movementSpeed; % Initial velocity
    
            Kp = 0.5; % Proportional gain (adjust as needed)
    
            % Preserve the direction of motion (sign of the velocity)
            direction = sign(currentVelocity); % +1 (forward), -1 (backward), etc.
    
            while abs(currentVelocity - desiredVelocity) > 0.05
                error = desiredVelocity - currentVelocity;
                controlSignal = Kp * error; % Apply proportional control
                
                % Ensure braking maintains the correct movement direction
                currentVelocity = currentVelocity + direction * controlSignal; 
    
                % Apply the adjusted velocity to all wheels
                app.sim.simxSetJointTargetVelocity(app.clientID, app.jointHandles.rollingJoint_rr, currentVelocity, app.sim.simx_opmode_streaming);
                app.sim.simxSetJointTargetVelocity(app.clientID, app.jointHandles.rollingJoint_rl, currentVelocity, app.sim.simx_opmode_streaming);
                app.sim.simxSetJointTargetVelocity(app.clientID, app.jointHandles.rollingJoint_fr, currentVelocity, app.sim.simx_opmode_streaming);
                app.sim.simxSetJointTargetVelocity(app.clientID, app.jointHandles.rollingJoint_fl, currentVelocity, app.sim.simx_opmode_streaming);
    
                pause(0.1);
            end
        end

        % Value changed function: BaseSlider
        function BaseSliderValueChanged(app, event)
            value = app.BaseSlider.Value;
            app.handleArmJoint(app.jointHandles.youBotArmJoint0, value);
        end

        % Value changed function: ShoulderSlider
        function ShoulderSliderValueChanged(app, event)
            value = app.ShoulderSlider.Value;
            app.handleArmJoint(app.jointHandles.youBotArmJoint1, value);
        end

        % Value changed function: ElbowSlider_2
        function ElbowSlider_2ValueChanged(app, event)
            value = app.ElbowSlider_2.Value;
            app.handleArmJoint(app.jointHandles.youBotArmJoint2, value);
        end

        % Value changed function: WristPitchSlider
        function WristPitchSliderValueChanged(app, event)
            value = app.WristPitchSlider.Value;
            app.handleArmJoint(app.jointHandles.youBotArmJoint3, value);
        end

        % Value changed function: WristRotateSlider
        function WristRotateSliderValueChanged(app, event)
            value = app.WristRotateSlider.Value;
            app.handleArmJoint(app.jointHandles.youBotArmJoint4, value);
        end

        % Button pushed function: OpengripperButton
        function OpengripperButtonPushed(app, event)
            % Desired target positions for opening
            joint1Pos = 0.04;
            joint2Pos = -0.04; % For youBotGripperJoint2
        
            % Smoothly move each joint to its target
            smoothSetJointTargetPosition(app, ...
                app.jointHandles.youBotGripperJoint1, ...
                joint1Pos);

            smoothSetJointTargetPosition(app, ...
                app.jointHandles.youBotGripperJoint2, ...
                joint2Pos);
            disp('Gripper Opened Smoothly.');
        end

        % Button pushed function: ClosegripperButton
        function ClosegripperButtonPushed(app, event)
            % Desired target positions for opening
            joint1Pos = 0;
            joint2Pos = 0; % For youBotGripperJoint2
        
            % Smoothly move each joint to its target
            smoothSetJointTargetPosition(app, ...
                app.jointHandles.youBotGripperJoint1, ...
                joint1Pos);

            smoothSetJointTargetPosition(app, ...
                app.jointHandles.youBotGripperJoint2, ...
                joint2Pos);
            disp('Gripper Opened Smoothly.');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @moveBotForward, true);
            app.forwardVisionAxes = uiaxes(app.UIFigure);
            title(app.forwardVisionAxes, 'Title')
            xlabel(app.forwardVisionAxes, 'X')
            ylabel(app.forwardVisionAxes, 'Y')
            zlabel(app.forwardVisionAxes, 'Z')
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.Text = '🔼';
            app.VelocitySliderLabel = uilabel(app.UIFigure);
            app.VelocitySliderLabel.Text = 'Velocity';
            app.VelocitySlider = uislider(app.UIFigure);
            app.VelocitySlider.Limits = [0 5];
            app.VelocitySlider.ValueChangedFcn = createCallbackFcn(app, @VelocitySliderValueChanged, true);
            app.VelocitySlider.MinorTicks = [0 1 2 3 4 5];
            app.ForwardButton = uibutton(app.UIFigure, 'push');
            app.ForwardButton.ButtonPushedFcn = createCallbackFcn(app, @moveBotForward, true);
            app.ForwardButton.Text = 'Forward';
            app.BackwardButton = uibutton(app.UIFigure, 'push');
            app.BackwardButton.ButtonPushedFcn = createCallbackFcn(app, @moveBotBackward, true);
            app.BackwardButton.Text = 'Backward';
            app.LeftButton = uibutton(app.UIFigure, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Text = 'Left';
            app.RightButton = uibutton(app.UIFigure, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @moveBotRight, true);
            app.RightButton.Text = 'Right';
            app.EmergencyStopButton = uibutton(app.UIFigure, 'push');
            app.EmergencyStopButton.ButtonPushedFcn = createCallbackFcn(app, @EmergencyStop, true);
            app.EmergencyStopButton.Text = 'Emergency Stop';
            app.BrakeButton = uibutton(app.UIFigure, 'push');
            app.BrakeButton.ButtonPushedFcn = createCallbackFcn(app, @Brake, true);
            app.BrakeButton.Text = 'Brake';
            app.ConnectionstatusLampLabel = uilabel(app.UIFigure);
            app.ConnectionstatusLampLabel.Text = 'Connection status';
            app.ConnectionstatusLamp = uilamp(app.UIFigure);
            app.WristPitchSliderLabel = uilabel(app.UIFigure);
            app.WristPitchSliderLabel.Text = 'Wrist Pitch';
            app.WristPitchSlider = uislider(app.UIFigure);
            app.WristPitchSlider.Limits = [-3 3];
            app.WristPitchSlider.MajorTicks = [-3 -2 -1 0 1 2 3 4 5];
            app.WristPitchSlider.ValueChangedFcn = createCallbackFcn(app, @WristPitchSliderValueChanged, true);
            app.WristPitchSlider.MinorTicks = [-2.5 -1.5 -0.5 0 0.5 1.5 2.5 3.5];
            app.ElbowSlider_2Label = uilabel(app.UIFigure);
            app.ElbowSlider_2Label.Text = 'Elbow';
            app.ElbowSlider_2 = uislider(app.UIFigure);
            app.ElbowSlider_2.Limits = [-3 3];
            app.ElbowSlider_2.MajorTicks = [-3 -2 -1 0 1 2 3 4 5];
            app.ElbowSlider_2.ValueChangedFcn = createCallbackFcn(app, @ElbowSlider_2ValueChanged, true);
            app.ElbowSlider_2.MinorTicks = [-2.5 -1.5 -0.5 0 0.5 1.5 2.5 3.5];
            app.ShoulderSlider_2Label = uilabel(app.UIFigure);
            app.ShoulderSlider_2Label.Text = 'Shoulder';
            app.ShoulderSlider = uislider(app.UIFigure);
            app.ShoulderSlider.Limits = [-3 3];
            app.ShoulderSlider.MajorTicks = [-3 -2 -1 0 1 2 3 4 5];
            app.ShoulderSlider.ValueChangedFcn = createCallbackFcn(app, @ShoulderSliderValueChanged, true);
            app.ShoulderSlider.MinorTicks = [-2.5 -1.5 -0.5 0 0.5 1.5 2.5 3.5];
            app.ShoulderSlider_2Label_2 = uilabel(app.UIFigure);
            app.ShoulderSlider_2Label_2.Text = 'Wrist Rotate';
            app.WristRotateSlider = uislider(app.UIFigure);
            app.WristRotateSlider.Limits = [-3 3];
            app.WristRotateSlider.MajorTicks = [-3 -2 -1 0 1 2 3 4 5];
            app.WristRotateSlider.ValueChangedFcn = createCallbackFcn(app, @WristRotateSliderValueChanged, true);
            app.WristRotateSlider.MinorTicks = [-2.5 -1.5 -0.5 0 0.5 1.5 2.5 3.5];
            app.BaseSliderLabel = uilabel(app.UIFigure);
            app.BaseSliderLabel.Text = 'Base';
            app.BaseSlider = uislider(app.UIFigure);
            app.BaseSlider.Limits = [-3 3];
            app.BaseSlider.MajorTicks = [-3 -2 -1 0 1 2 3 4 5];
            app.BaseSlider.ValueChangedFcn = createCallbackFcn(app, @BaseSliderValueChanged, true);
            app.BaseSlider.MinorTicks = [-2.5 -1.5 -0.5 0 0.5 1.5 2.5 3.5];
            app.OpengripperButton = uibutton(app.UIFigure, 'push');
            app.OpengripperButton.ButtonPushedFcn = createCallbackFcn(app, @OpengripperButtonPushed, true);
            app.OpengripperButton.Text = 'Open gripper';
            app.ClosegripperButton = uibutton(app.UIFigure, 'push');
            app.ClosegripperButton.ButtonPushedFcn = createCallbackFcn(app, @ClosegripperButtonPushed, true);
            app.ClosegripperButton.Text = 'Close gripper';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = controls_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end